classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        NghiemTab                  matlab.ui.container.Tab
        Ketqua                     matlab.ui.control.NumericEditField
        KtquEditFieldLabel         matlab.ui.control.Label
        Solanlap                   matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel        matlab.ui.control.Label
        saiso                      matlab.ui.control.NumericEditField
        SaischophpEditFieldLabel   matlab.ui.control.Label
        b                          matlab.ui.control.NumericEditField
        nEditFieldLabel            matlab.ui.control.Label
        a                          matlab.ui.control.NumericEditField
        TEditFieldLabel            matlab.ui.control.Label
        NhapKhoangNghiem           matlab.ui.control.EditField
        NhpkhongphnlynghimEditFieldLabel  matlab.ui.control.Label
        EditField                  matlab.ui.control.EditField
        EditFieldLabel             matlab.ui.control.Label
        NhapPT                     matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel  matlab.ui.control.Label
        Newton                     matlab.ui.control.Button
        Lap                        matlab.ui.control.Button
        Chiadoi                    matlab.ui.control.Button
        Result                     matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        NoisuyTab                  matlab.ui.container.Tab
        HoiquyTab                  matlab.ui.container.Tab
        DaohamTab                  matlab.ui.container.Tab
        SaiSoDaoHam                matlab.ui.control.EditField
        ChngitrsaisOhhocOh2Label   matlab.ui.control.Label
        BuochDaoHam                matlab.ui.control.EditField
        NhpbchLabel                matlab.ui.control.Label
        GiatriDaoHam               matlab.ui.control.NumericEditField
        NhpcntnhTchphnLabel_2      matlab.ui.control.Label
        Hamso_DaoHam               matlab.ui.control.EditField
        NhphmsEditFieldLabel_2     matlab.ui.control.Label
        Ydata_DaoHam               matlab.ui.control.NumericEditField
        NhpdliuyLabel_2            matlab.ui.control.Label
        Xdata_DaoHam               matlab.ui.control.NumericEditField
        NhpdliuxLabel_2            matlab.ui.control.Label
        PhuongPhapButtonGroup_2    matlab.ui.container.ButtonGroup
        TrungtmButton              matlab.ui.control.ToggleButton
        XpxtinliButton             matlab.ui.control.ToggleButton
        KetQuaButton_DaoHam        matlab.ui.control.Button
        bonustext_2                matlab.ui.control.Label
        TichPhanTab                matlab.ui.container.Tab
        dataN                      matlab.ui.control.NumericEditField
        NhpNLabel                  matlab.ui.control.Label
        Range                      matlab.ui.control.NumericEditField
        NhpcntnhTchphnLabel        matlab.ui.control.Label
        PhuongPhapButtonGroup      matlab.ui.container.ButtonGroup
        Simpson38Button            matlab.ui.control.ToggleButton
        Simpson13Button            matlab.ui.control.ToggleButton
        HnhThangButton             matlab.ui.control.ToggleButton
        KetQuaButton               matlab.ui.control.Button
        Hamso                      matlab.ui.control.EditField
        NhphmsEditFieldLabel       matlab.ui.control.Label
        bonustext                  matlab.ui.control.Label
        Ydata                      matlab.ui.control.NumericEditField
        NhpdliuyLabel              matlab.ui.control.Label
        Xdata                      matlab.ui.control.NumericEditField
        NhpdliuxLabel              matlab.ui.control.Label
        IntroTab                   matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Chiadoi
        function ChiadoiButtonPushed(app, event)
             syms x;
    f = str2func(['@(x)',app.NhapPT.Value]);
    app.Solanlap.Value = 0;    %khai báo biến đếm số lần lặp
    while(1)
        app.Solanlap.Value = app.Solanlap.Value + 1;    %mỗi lần lặp lại biến solanlap +1
        c = (app.a.Value + app.b.Value) / 2;              %tính c bằng (a+b)/2
        if(f(c) * f(app.a.Value) < 0)         %nếu f(c)*f(a)<0 có nghĩa khoảng (a,c) có nghiệm
            app.b.Value = c;                %thay b=c
        else
            app.a.Value = c;                %ngược lại nếu f(c)*f(a)>0 tức trong khoảng (a,c) không có nghiệm, nghiệm nằm trong khoảng (b,c) 
        end
        e = abs(app.b.Value - app.a.Value);             %tính trị tuyệt đối b-1
        if(e <= app.saiso.Value)             %nếu e< sai số thì thoát lặp
            break
        end
    end
    app.Ketqua.Value = app.a.Value;               %nghiệm của phương trình bắng a

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghiemTab = uitab(app.TabGroup);
            app.NghiemTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghiemTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Result = uibutton(app.NghiemTab, 'push');
            app.Result.Text = 'Kết quả';
            app.Chiadoi = uibutton(app.NghiemTab, 'push');
            app.Chiadoi.ButtonPushedFcn = createCallbackFcn(app, @ChiadoiButtonPushed, true);
            app.Chiadoi.Text = 'Chia đôi';
            app.Lap = uibutton(app.NghiemTab, 'push');
            app.Lap.Text = 'Lặp';
            app.Newton = uibutton(app.NghiemTab, 'push');
            app.Newton.Text = 'Newton(Tiếp tuyến)';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhapPT = uieditfield(app.NghiemTab, 'text');
            app.NhapPT.Value = 'x-sin(x)-0.25';
            app.EditFieldLabel = uilabel(app.NghiemTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.NghiemTab, 'text');
            app.EditField.Value = 'Chọn phương pháp tìm nghiệm ';
            app.NhpkhongphnlynghimEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpkhongphnlynghimEditFieldLabel.Text = 'Nhập khoảng phân ly nghiệm';
            app.NhapKhoangNghiem = uieditfield(app.NghiemTab, 'text');
            app.NhapKhoangNghiem.Value = 'Nhập khoảng phân ly nghiệm';
            app.TEditFieldLabel = uilabel(app.NghiemTab);
            app.TEditFieldLabel.Text = 'Từ';
            app.a = uieditfield(app.NghiemTab, 'numeric');
            app.a.Value = 1;
            app.nEditFieldLabel = uilabel(app.NghiemTab);
            app.nEditFieldLabel.Text = 'Đến';
            app.b = uieditfield(app.NghiemTab, 'numeric');
            app.b.Value = 2;
            app.SaischophpEditFieldLabel = uilabel(app.NghiemTab);
            app.SaischophpEditFieldLabel.Text = 'Sai số cho phép';
            app.saiso = uieditfield(app.NghiemTab, 'numeric');
            app.saiso.Value = 0.005;
            app.SlnlpEditFieldLabel = uilabel(app.NghiemTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.Solanlap = uieditfield(app.NghiemTab, 'numeric');
            app.KtquEditFieldLabel = uilabel(app.NghiemTab);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.Ketqua = uieditfield(app.NghiemTab, 'numeric');
            app.NoisuyTab = uitab(app.TabGroup);
            app.NoisuyTab.Title = 'Nội Suy';
            app.HoiquyTab = uitab(app.TabGroup);
            app.HoiquyTab.Title = 'Hồi Quy';
            app.DaohamTab = uitab(app.TabGroup);
            app.DaohamTab.Title = 'Đạo Hàm';
            app.bonustext_2 = uilabel(app.DaohamTab);
            app.bonustext_2.Text = 'Hoặc';
            app.KetQuaButton_DaoHam = uibutton(app.DaohamTab, 'push');
            app.KetQuaButton_DaoHam.Text = 'Kết Quả';
            app.PhuongPhapButtonGroup_2 = uibuttongroup(app.DaohamTab);
            app.PhuongPhapButtonGroup_2.Title = 'Chọn phương pháp Đạo hàm ';
            app.XpxtinliButton = uitogglebutton(app.PhuongPhapButtonGroup_2);
            app.XpxtinliButton.Text = 'Xấp xỉ tiến lùi ';
            app.XpxtinliButton.Value = true;
            app.TrungtmButton = uitogglebutton(app.PhuongPhapButtonGroup_2);
            app.TrungtmButton.Text = 'Trung tâm ';
            app.NhpdliuxLabel_2 = uilabel(app.DaohamTab);
            app.NhpdliuxLabel_2.Text = 'Nhập dữ liệu x';
            app.Xdata_DaoHam = uieditfield(app.DaohamTab, 'numeric');
            app.NhpdliuyLabel_2 = uilabel(app.DaohamTab);
            app.NhpdliuyLabel_2.Text = 'Nhập dữ liệu y';
            app.Ydata_DaoHam = uieditfield(app.DaohamTab, 'numeric');
            app.NhphmsEditFieldLabel_2 = uilabel(app.DaohamTab);
            app.NhphmsEditFieldLabel_2.Text = 'Nhập hàm số';
            app.Hamso_DaoHam = uieditfield(app.DaohamTab, 'text');
            app.NhpcntnhTchphnLabel_2 = uilabel(app.DaohamTab);
            app.NhpcntnhTchphnLabel_2.Text = 'Nhập giá trị cần tính Đạo hàm ';
            app.GiatriDaoHam = uieditfield(app.DaohamTab, 'numeric');
            app.NhpbchLabel = uilabel(app.DaohamTab);
            app.NhpbchLabel.Text = 'Nhập bước h ';
            app.BuochDaoHam = uieditfield(app.DaohamTab, 'text');
            app.ChngitrsaisOhhocOh2Label = uilabel(app.DaohamTab);
            app.ChngitrsaisOhhocOh2Label.Text = {'Chọn giá trị sai số '; 'O(h) hoặc O(h^2)'};
            app.SaiSoDaoHam = uieditfield(app.DaohamTab, 'text');
            app.TichPhanTab = uitab(app.TabGroup);
            app.TichPhanTab.Title = 'Tích Phân';
            app.NhpdliuxLabel = uilabel(app.TichPhanTab);
            app.NhpdliuxLabel.Text = 'Nhập dữ liệu x';
            app.Xdata = uieditfield(app.TichPhanTab, 'numeric');
            app.NhpdliuyLabel = uilabel(app.TichPhanTab);
            app.NhpdliuyLabel.Text = 'Nhập dữ liệu y';
            app.Ydata = uieditfield(app.TichPhanTab, 'numeric');
            app.bonustext = uilabel(app.TichPhanTab);
            app.bonustext.Text = 'Hoặc';
            app.NhphmsEditFieldLabel = uilabel(app.TichPhanTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.Hamso = uieditfield(app.TichPhanTab, 'text');
            app.KetQuaButton = uibutton(app.TichPhanTab, 'push');
            app.KetQuaButton.Text = 'Kết Quả';
            app.PhuongPhapButtonGroup = uibuttongroup(app.TichPhanTab);
            app.PhuongPhapButtonGroup.Title = 'Chọn phương pháp Tích phân';
            app.HnhThangButton = uitogglebutton(app.PhuongPhapButtonGroup);
            app.HnhThangButton.Text = 'Hình Thang';
            app.HnhThangButton.Value = true;
            app.Simpson13Button = uitogglebutton(app.PhuongPhapButtonGroup);
            app.Simpson13Button.Text = 'Simpson 1/3';
            app.Simpson38Button = uitogglebutton(app.PhuongPhapButtonGroup);
            app.Simpson38Button.Text = 'Simpson 3/8';
            app.NhpcntnhTchphnLabel = uilabel(app.TichPhanTab);
            app.NhpcntnhTchphnLabel.Text = 'Nhập cận để tính Tích phân';
            app.Range = uieditfield(app.TichPhanTab, 'numeric');
            app.NhpNLabel = uilabel(app.TichPhanTab);
            app.NhpNLabel.Text = 'Nhập N';
            app.dataN = uieditfield(app.TichPhanTab, 'numeric');
            app.IntroTab = uitab(app.TabGroup);
            app.IntroTab.Title = 'Giới thiệu nhóm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end